classdef labelLightning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LightningfootagesegmentationUIFigure  matlab.ui.Figure
        SegmentationPanel             matlab.ui.container.Panel
        Networkfile_Edit              matlab.ui.control.EditField
        Outputpath_Edit               matlab.ui.control.EditField
        Inputfolder_Edit              matlab.ui.control.EditField
        Inputfolder_Button            matlab.ui.control.Button
        Networkfile_Button            matlab.ui.control.Button
        Outputpath_Button             matlab.ui.control.Button
        Comparewithexpected_CheckBox  matlab.ui.control.CheckBox
        Pixellabels_Edit              matlab.ui.control.EditField
        Pixellabels_Button            matlab.ui.control.Button
        Makeoverlay_CheckBox          matlab.ui.control.CheckBox
        Excecute_Button               matlab.ui.control.Button
        Inputfolder_Label             matlab.ui.control.Label
        Networkfile_Label             matlab.ui.control.Label
        Outputpath_Label              matlab.ui.control.Label
        Pixellabels_Label             matlab.ui.control.Label
    end

properties (Access = public)
    file;
    path1;
    AppPos;
    progressApp;
    output;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Comparewithexpected_CheckBox
        function makeVisiable(app, event)
            value = app.Comparewithexpected_CheckBox.Value;
            if value
                app.Pixellabels_Label.Visible = 'on';
                app.Pixellabels_Button.Visible = 'on';
                app.Pixellabels_Edit.Visible = 'on';
            else
                app.Pixellabels_Label.Visible = 'off';
                app.Pixellabels_Button.Visible = 'off';
                app.Pixellabels_Edit.Visible = 'off';
            end
            
        end

        % Button pushed function: Inputfolder_Button
        function Browse_input(app, event)
            sequenceDir = uigetdir(app.Inputfolder_Edit.Value,'Select the sequence to load');
            if ~sequenceDir
                disp('No directory has been defined')
            else
                app.Inputfolder_Edit.Value = sequenceDir;
                basePath = fileparts(sequenceDir);
                app.Pixellabels_Edit.Value = strcat(basePath,'/mask');
            end
            
        end

        % Button pushed function: Networkfile_Button
        function Browse_network(app, event)
            pathToNetworkFile = fileparts(app.Networkfile_Edit.Value);
            [app.file,app.path1] = uigetfile('*.mat',...
                'Select the Network File to load',pathToNetworkFile);
            if ~app.file
                disp('Network file has not been specified')
            else
                app.Networkfile_Edit.Value = [app.path1 app.file];
            end           
        end

        % Button pushed function: Outputpath_Button
        function Browse_output(app, event)
            outputDir = uigetdir(app.Outputpath_Edit.Value,'Select the output diretory');
            if ~outputDir
                disp('No directory has been defined, using standard tmp')
            else
                app.Outputpath_Edit.Value = outputDir;
            end
        end

        % Button pushed function: Pixellabels_Button
        function Browse_labels(app, event)
            labelDir = uigetdir(app.Pixellabels_Edit.Value,'Select the masks to load');
            if ~labelDir
                disp('No directory has been defined')
            else
                app.Pixellabels_Edit.Value = labelDir;
                basePath = fileparts(labelDir);
                app.Inputfolder_Edit.Value = strcat(basePath,'/tif');
            end
            
        end

        % Button pushed function: Excecute_Button
        function runSegmentation(app, event)
            %% Export all the required variable
            loadLabels;
            app.Excecute_Button.Enable = 'off';
            %app.AppPos = app.LightningfootagesegmentationUIFigure.Position
            %app.LightningfootagesegmentationUIFigure.Visible = 'off';
            segmentResults(app.Networkfile_Edit.Value, app.Inputfolder_Edit.Value, ...
                app.Outputpath_Edit.Value, app.Makeoverlay_CheckBox.Value, ...
                app.Comparewithexpected_CheckBox.Value, app.Pixellabels_Edit.Value);
            
            outputDIR = fullfile(app.Outputpath_Edit.Value, 'output');
            outputDs = imageDatastore(outputDIR, 'FileExtensions', '.png');
            %export the OutputDs to the workspace
            eventsCellArray = createEvents(outputDs);
            assignin('base', 'eventCellArray', eventsCellArray);
            app.Excecute_Button.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LightningfootagesegmentationUIFigure = uifigure('Visible', 'off');
            app.LightningfootagesegmentationUIFigure.Name = 'Lightning footage segmentation';
            app.SegmentationPanel = uipanel(app.LightningfootagesegmentationUIFigure);
            app.SegmentationPanel.Title = 'Segmentation';
            app.Networkfile_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Networkfile_Edit.Value = '~/Raiden/networks/cache/';
            app.Outputpath_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Outputpath_Edit.Value = '/home/tyson/Raiden/networks/output/test';
            app.Inputfolder_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Inputfolder_Edit.Value = '/mnt/Shield/Raiden/data/sequences/';
            app.Inputfolder_Button = uibutton(app.SegmentationPanel, 'push');
            app.Inputfolder_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_input, true);
            app.Inputfolder_Button.Icon = 'folder.png';
            app.Inputfolder_Button.Tooltip = {'Browse'};
            app.Inputfolder_Button.Text = '';
            app.Networkfile_Button = uibutton(app.SegmentationPanel, 'push');
            app.Networkfile_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_network, true);
            app.Networkfile_Button.Icon = 'folder.png';
            app.Networkfile_Button.Tooltip = {'Browse'};
            app.Networkfile_Button.Text = '';
            app.Outputpath_Button = uibutton(app.SegmentationPanel, 'push');
            app.Outputpath_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_output, true);
            app.Outputpath_Button.Icon = 'folder.png';
            app.Outputpath_Button.Tooltip = {'Browse'};
            app.Outputpath_Button.Text = '';
            app.Comparewithexpected_CheckBox = uicheckbox(app.SegmentationPanel);
            app.Comparewithexpected_CheckBox.ValueChangedFcn = createCallbackFcn(app, @makeVisiable, true);
            app.Comparewithexpected_CheckBox.Tooltip = {'Enable the overlay of the input image and pixel segmentation'};
            app.Comparewithexpected_CheckBox.Text = ' Compare with expected';
            app.Pixellabels_Edit = uieditfield(app.SegmentationPanel, 'text');
            app.Pixellabels_Edit.Visible = 'off';
            app.Pixellabels_Edit.Value = '~/Desktop/reduced/mask';
            app.Pixellabels_Button = uibutton(app.SegmentationPanel, 'push');
            app.Pixellabels_Button.ButtonPushedFcn = createCallbackFcn(app, @Browse_labels, true);
            app.Pixellabels_Button.Icon = 'folder.png';
            app.Pixellabels_Button.Visible = 'off';
            app.Pixellabels_Button.Tooltip = {'Browse'};
            app.Pixellabels_Button.Text = '';
            app.Makeoverlay_CheckBox = uicheckbox(app.SegmentationPanel);
            app.Makeoverlay_CheckBox.Text = ' Make overlay';
            app.Excecute_Button = uibutton(app.SegmentationPanel, 'push');
            app.Excecute_Button.ButtonPushedFcn = createCallbackFcn(app, @runSegmentation, true);
            app.Excecute_Button.Text = 'Excecute';
            app.Inputfolder_Label = uilabel(app.SegmentationPanel);
            app.Inputfolder_Label.Text = 'Input folder';
            app.Networkfile_Label = uilabel(app.SegmentationPanel);
            app.Networkfile_Label.Text = 'Network file';
            app.Outputpath_Label = uilabel(app.SegmentationPanel);
            app.Outputpath_Label.Text = 'Output path';
            app.Pixellabels_Label = uilabel(app.SegmentationPanel);
            app.Pixellabels_Label.Visible = 'off';
            app.Pixellabels_Label.Text = 'Pixel labels';
            % Show the figure after all components are created
            app.LightningfootagesegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labelLightning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LightningfootagesegmentationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LightningfootagesegmentationUIFigure)
        end
    end
end